classdef interfzIMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DropDown                      matlab.ui.control.DropDown
        BOTONGRAFICAR_3               matlab.ui.control.Button
        ES_9                          matlab.ui.control.Label
        ES_8                          matlab.ui.control.Label
        media                         matlab.ui.control.Label
        desviacion                    matlab.ui.control.Label
        BOTONGRAFICAR_2               matlab.ui.control.Button
        ES_7                          matlab.ui.control.Label
        ECV                           matlab.ui.control.Label
        fisica                        matlab.ui.control.Label
        ES_6                          matlab.ui.control.Label
        ES_5                          matlab.ui.control.Label
        ES_4                          matlab.ui.control.Label
        ES_3                          matlab.ui.control.Label
        DatosadicionalesButton        matlab.ui.control.Button
        TipodegraficaDropDown         matlab.ui.control.DropDown
        TipodegraficaDropDownLabel    matlab.ui.control.Label
        ID                            matlab.ui.control.NumericEditField
        IDparticipanteEditFieldLabel  matlab.ui.control.Label
        alcohol                       matlab.ui.control.Label
        fumador                       matlab.ui.control.Label
        edades                        matlab.ui.control.Label
        mmHg                          matlab.ui.control.Label
        DATO_presion                  matlab.ui.control.Label
        ES_2                          matlab.ui.control.Label
        Presionarteriarmedia          matlab.ui.control.Button
        DATO_2                        matlab.ui.control.Label
        DATO                          matlab.ui.control.Label
        ES                            matlab.ui.control.Label
        calcularIMCButton             matlab.ui.control.Button
        TITULO                        matlab.ui.control.Label
        grafica                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        HeartData %tabla con datos
    end
    
    methods (Access = private)
        
        function preprocesamiento(app)
            app.HeartData.height=app.HeartData.height/100;
        end
        function edad(app)
         app.HeartData.age=app.HeartData.age/365;
        end

        function alco(app)
        app.HeartData.alco = categorical(app.HeartData.alco, [0 1], {'No toma','Si toma'});

         end
        

        function fumar(app)
        app.HeartData.smoke = categorical(app.HeartData.smoke, [0 1], {'No fuma', 'Si Fuma'});

        end

        function actividad(app)
        app.HeartData.active = categorical(app.HeartData.active, [0 1], {'No hace actividad fisica', 'Si hace actividad fisica'});

        end
        
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load ("tabla.mat","heart_data")
            app.HeartData=heart_data;
            preprocesamiento(app);
            edad(app);
            alco(app);
            fumar(app);
            actividad(app);
         
        end

        % Button pushed function: calcularIMCButton
        function calcularIMCButtonPushed(app, event)
            id=app.ID.Value;
            peso=app.HeartData.weight(id);
            altura=app.HeartData.height(id);
            imc = peso/altura^2;
            if (imc<16)
            app.DATO.Text=(num2str(imc,"%.2f"));
            app.DATO_2.Text=("delgadez severa");

            elseif (imc >= 16) && (imc <= 16.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("delgadez moderada");
             
            elseif (imc >= 17) && (imc <= 18.49)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("delgadez leve");

             elseif (imc >= 18.5) && (imc <= 24.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Normal");

             elseif (imc >= 25) && (imc <= 29.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Preobeso");

             elseif (imc >= 30) && (imc <= 34.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Obesidad leve");

             elseif (imc >= 35) && (imc <= 39.99)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Obesidad media");

             elseif (imc >= 40)
             app.DATO.Text=(num2str(imc,"%.2f"));
             app.DATO_2.Text=("Obesidad morbida");

            end
      
         
        end

        % Button pushed function: Presionarteriarmedia
        function PresionarteriarmediaButtonPushed(app, event)
            id=app.ID.Value;
            presionsis=app.HeartData.ap_hi(id);
            presiondias=app.HeartData.ap_lo(id);
            presionmedia=(((presionsis)+(2*presiondias))/(3));
            app.DATO_presion.Text=(num2str(presionmedia,"%.2f"));
            app.mmHg.Text=("mmHg");

        end

        % Button pushed function: DatosadicionalesButton
        function DatosadicionalesButtonPushed(app, event)
            id=app.ID.Value;
            
            edad=(app.HeartData.age(id));
              app.edades.Text=(num2str(edad,"%.0f"));

            alco=(app.HeartData.alco(id));
              app.alcohol.Text=(string(alco));

             fuma=(app.HeartData.smoke(id));
              app.fumador.Text=(string(fuma));

             fisic=(app.HeartData.active(id));
             app.fisica.Text=(string(fisic));

             ecv=(app.HeartData.cardio(id));
             if (ecv==1)
             app.ECV.Text=("Si tiene ECV");
             elseif (ecv==0)
             app.ECV.Text=("No tiene ECV");
             end
        end

        % Button pushed function: BOTONGRAFICAR_2
        function BOTONGRAFICAR_2ButtonPushed(app, event)

         value = app.TipodegraficaDropDown.Value;
            if (value==1)
                cla(app.grafica,"reset");
hombres2040 = 0;
Mujeres2040 = 0;
hombres4050 = 0;
Mujeres4050 = 0;
hombres5060 = 0;
Mujeres5060 = 0;
hombresmay = 0;
Mujeresmay = 0;

for i = 1:length(app.HeartData.age)
    if app.HeartData.cardio(i) == 1 
       
        if app.HeartData.age(i) <= 40
            if app.HeartData.gender(i) == 1
                hombres2040 = hombres2040 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres2040 = Mujeres2040 + 1;
            end
        
        elseif app.HeartData.age(i) > 40 && app.HeartData.age(i) <= 50
            if app.HeartData.gender(i) == 1
                hombres4050 = hombres4050 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres4050 = Mujeres4050 + 1;
            end
        %
        elseif app.HeartData.age(i) > 50 && app.HeartData.age(i) <= 60
            if app.HeartData.gender(i) == 1
                hombres5060 = hombres5060 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres5060 = Mujeres5060 + 1;
            end
        elseif app.HeartData.age(i) > 60
            if app.HeartData.gender(i) == 1
                hombresmay = hombresmay + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeresmay = Mujeresmay + 1;
            end
        end
    end
end


    x = [ "Menor a 40", "entre 40 y 50 años","entre 50 y 60 años", "más de 60 años"];
    y = [hombres2040 Mujeres2040; hombres4050 Mujeres4050; hombres5060 Mujeres5060;hombresmay Mujeresmay];
    bar(app.grafica,x,y)
    set(get(app.grafica(1),'ylabel'),'string','Cantidad de personas');
    set(get(app.grafica(1),'xlabel'),'string','Edad de las personas');
    set(get(app.grafica(1),'title'),'string','Enfermedad cardiovascular');
    legend(app.grafica,'Hombres','Mujeres');


     elseif  (value==2)
     cla(app.grafica,"reset");
        concardioIMC = zeros(1, length(app.HeartData.age));
        sincardioIMC = zeros(1, length(app.HeartData.age));
        concardioCol = zeros(1, length(app.HeartData.age));
        sincardioCol = zeros(1, length(app.HeartData.age));
        IMC2 = zeros(1, length(app.HeartData.age));
        
        
        for i = 1:length(app.HeartData.age)

            peso=app.HeartData.weight(i);
            altura=app.HeartData.height(i);
            IMC2(i) = peso/altura^2;

            if app.HeartData.cardio(i) == 1
                concardioIMC(i)=concardioIMC(i)+IMC2(i);
                concardioCol(i)=concardioCol(i)+ app.HeartData.cholesterol(i);
            elseif app.HeartData.cardio(i) == 0
                sincardioIMC(i)=sincardioIMC(i)+IMC2(i);
                sincardioCol(i)=sincardioCol(i)+app.HeartData.cholesterol(i);
            end
        end
        
        
        scatter(app.grafica,concardioIMC, concardioCol,'MarkerFaceColor', 'g');
        hold(app.grafica,'on');
        scatter(app.grafica,sincardioIMC, sincardioCol,'MarkerFaceColor', 'r');
        hold(app.grafica,'on');
        set(get(app.grafica(1),'xlabel'),'string','IMC');
        set(get(app.grafica(1),'ylabel'),'string','COLESTEROL');
        set(get(app.grafica(1),'title'),'string',' IMC VS COLESTEROL');
        
        legend(app.grafica,'Con enfermedad cardíaca','Sin enfermedad cardiaca');


        elseif  (value==3)
        cla(app.grafica,"reset");
        concardioGlu = zeros(1, length(app.HeartData.age));
        sincardioGlu = zeros(1, length(app.HeartData.age));
        concardioPres = zeros(1, length(app.HeartData.age));
        sincardioPres = zeros(1, length(app.HeartData.age));
        PresionMedia = zeros(1, length(app.HeartData.age));

        for i = 1:length(app.HeartData.age)
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            PresionMedia(i)=(((presionsis)+(2*presiondias))/(3));

            if app.HeartData.cardio(i)== 1
                concardioGlu(i)=concardioGlu(i)+app.HeartData.gluc(i);
                concardioPres(i)=concardioPres(i)+PresionMedia(i);
            elseif app.HeartData.cardio(i)== 0
                sincardioGlu(i)=sincardioGlu(i)+app.HeartData.gluc(i);
                sincardioPres(i)=sincardioPres(i)+PresionMedia(i);
            end
        end
        

        scatter(app.grafica,concardioGlu, concardioPres, 'MarkerFaceColor', 'b');
        hold(app.grafica,'on');
        scatter(app.grafica,sincardioGlu, sincardioPres, 'MarkerFaceColor', 'r');
        hold(app.grafica,'on');
        set(get(app.grafica(1),'xlabel'),'string','GLUCOSA');
        set(get(app.grafica(1),'ylabel'),'string','PRESION ARTERIAL MEDIA');
        set(get(app.grafica(1),'title'),'string',' GLUCOSA VS PRESION ARTERIAL');
        
        legend(app.grafica,'Con enfermedad cardíaca','Sin enfermedad cardiaca');

            end

    
        end

        % Value changed function: TipodegraficaDropDown
        function TipodegraficaDropDownValueChanged(app, event)
           
        end

        % Button pushed function: BOTONGRAFICAR_3
        function BOTONGRAFICAR_3ButtonPushed(app, event)
   value = app.DropDown.Value;
  if (value==1)
             
             
             IMC2 = zeros(1, length(app.HeartData.weight));

            
        for i = 1:length(app.HeartData.cardio)
            

              peso=app.HeartData.weight(i);
      
              altura=app.HeartData.height(i);
            
               IMC2(i) = peso/altura^2;
               
        end 
        app.desviacion.Text=num2str(std(IMC2));
        app.media.Text=num2str(mean(IMC2));
       
       
            elseif (value==2)
             
             
             IMC3 = zeros(1, length(app.HeartData.weight));

            
        for i = 1:length(app.HeartData.cardio)
            if app.HeartData.cardio(i) == 1

              peso=app.HeartData.weight(i);
      
              altura=app.HeartData.height(i);
            
               IMC3(i) = peso/altura^2;
               
            end
        end
        app.desviacion.Text=num2str(std(IMC3));
        app.media.Text=num2str(mean(IMC3));

            elseif (value==3)
                
            presionmedia = zeros(1, length(app.HeartData.ap_hi));


        for i = 1:length(app.HeartData.cardio)
            if app.HeartData.cardio(i) == 0
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            presionmedia(i) =(((presionsis)+(2*presiondias))/(3));
            end
        end
        app.desviacion.Text=(num2str(std(presionmedia)));
        app.media.Text=num2str(mean(presionmedia));


        elseif (value==4)
                
            presionmedia1 = zeros(1, length(app.HeartData.ap_hi));
     

        for i = 1:length(app.HeartData.cardio)
            if app.HeartData.cardio(i) == 1
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            presionmedia1(i) =(((presionsis)+(2*presiondias))/(3));
            
            end
        end
        app.desviacion.Text=(num2str(std(presionmedia1)));
        app.media.Text=num2str(mean(presionmedia1));

         elseif (value==5)
        colesterol = zeros(1, length(app.HeartData.cholesterol));

         for i = 1:length(app.HeartData.cardio)
            if app.HeartData.cardio(i) == 0
            colesterol(i)=app.HeartData.cholesterol(i);
            
            end
        end
               app.media.Text=(num2str(mean(colesterol)));
               app.desviacion.Text=(num2str(std(colesterol)));

         elseif (value==6)

         colesterol = zeros(1, length(app.HeartData.cholesterol));

         for i = 1:length(app.HeartData.cardio)
            if app.HeartData.cardio(i) == 1
            colesterol(i)=app.HeartData.cholesterol(i);
            
            end
        end
               app.media.Text=(num2str(mean(colesterol)));
               app.desviacion.Text=(num2str(std(colesterol)));
  end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            app.TITULO = uilabel(app.UIFigure);
            app.TITULO.Text = 'Analisis indice masa corporal';
            app.calcularIMCButton = uibutton(app.UIFigure, 'push');
            app.calcularIMCButton.ButtonPushedFcn = createCallbackFcn(app, @calcularIMCButtonPushed, true);
            app.calcularIMCButton.Text = 'calcular IMC';
            app.ES = uilabel(app.UIFigure);
            app.ES.Text = 'Es:';
            app.DATO = uilabel(app.UIFigure);
            app.DATO.Text = '';
            app.DATO_2 = uilabel(app.UIFigure);
            app.DATO_2.Text = '';
            app.Presionarteriarmedia = uibutton(app.UIFigure, 'push');
            app.Presionarteriarmedia.ButtonPushedFcn = createCallbackFcn(app, @PresionarteriarmediaButtonPushed, true);
            app.Presionarteriarmedia.Text = 'calcular PAM';
            app.ES_2 = uilabel(app.UIFigure);
            app.ES_2.Text = 'Es:';
            app.DATO_presion = uilabel(app.UIFigure);
            app.DATO_presion.Text = '';
            app.mmHg = uilabel(app.UIFigure);
            app.mmHg.Text = '';
            app.edades = uilabel(app.UIFigure);
            app.edades.Text = '';
            app.fumador = uilabel(app.UIFigure);
            app.fumador.Text = '';
            app.alcohol = uilabel(app.UIFigure);
            app.alcohol.Text = '';
            app.IDparticipanteEditFieldLabel = uilabel(app.UIFigure);
            app.IDparticipanteEditFieldLabel.Text = 'ID participante';
            app.ID = uieditfield(app.UIFigure, 'numeric');
            app.ID.Limits = [1 7000];
            app.ID.ValueDisplayFormat = '%.0f';
            app.ID.Placeholder = '1';
            app.ID.Value = 1;
            app.TipodegraficaDropDownLabel = uilabel(app.UIFigure);
            app.TipodegraficaDropDownLabel.Text = 'Tipo de grafica';
            app.TipodegraficaDropDown = uidropdown(app.UIFigure);
            app.TipodegraficaDropDown.Items = {'Grafica de barras - hombres y mujeres', 'Gráfica de dispersión - Índice de masa corporal vs Colesterol', 'Gráfica de dispersión - Nivel de glucosa vs Presión arterial media'};
            app.TipodegraficaDropDown.ItemsData = [1 2 3];
            app.TipodegraficaDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodegraficaDropDownValueChanged, true);
            app.TipodegraficaDropDown.Value = 1;
            app.DatosadicionalesButton = uibutton(app.UIFigure, 'push');
            app.DatosadicionalesButton.ButtonPushedFcn = createCallbackFcn(app, @DatosadicionalesButtonPushed, true);
            app.DatosadicionalesButton.Text = 'Datos adicionales';
            app.ES_3 = uilabel(app.UIFigure);
            app.ES_3.Text = 'Edad:';
            app.ES_4 = uilabel(app.UIFigure);
            app.ES_4.Text = 'Fuma:';
            app.ES_5 = uilabel(app.UIFigure);
            app.ES_5.Text = 'Alcohol';
            app.ES_6 = uilabel(app.UIFigure);
            app.ES_6.Text = 'Actividad fisica:';
            app.fisica = uilabel(app.UIFigure);
            app.fisica.Text = '';
            app.ECV = uilabel(app.UIFigure);
            app.ECV.Text = '';
            app.ES_7 = uilabel(app.UIFigure);
            app.ES_7.Text = 'ECV:';
            app.BOTONGRAFICAR_2 = uibutton(app.UIFigure, 'push');
            app.BOTONGRAFICAR_2.ButtonPushedFcn = createCallbackFcn(app, @BOTONGRAFICAR_2ButtonPushed, true);
            app.BOTONGRAFICAR_2.Text = 'GRAFICAR';
            app.desviacion = uilabel(app.UIFigure);
            app.desviacion.Text = '';
            app.media = uilabel(app.UIFigure);
            app.media.Text = '';
            app.ES_8 = uilabel(app.UIFigure);
            app.ES_8.Text = 'Desviación:';
            app.ES_9 = uilabel(app.UIFigure);
            app.ES_9.Text = 'Media:';
            app.BOTONGRAFICAR_3 = uibutton(app.UIFigure, 'push');
            app.BOTONGRAFICAR_3.ButtonPushedFcn = createCallbackFcn(app, @BOTONGRAFICAR_3ButtonPushed, true);
            app.BOTONGRAFICAR_3.Text = 'GRAFICAR';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'IMC sin enfernedad', 'IMC con enfernedad', 'Presión arterial sin enfermedad', 'Presión arterial con enfermedad', 'Colesterol sin enfermedad', 'Colesterol con enfermedad'};
            app.DropDown.ItemsData = [1 2 3 4 5 6];
            app.DropDown.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfzIMC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end